classdef DAQDataAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LiveViewPanel                 matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        LoadFigureButton              matlab.ui.control.Button
        SetoffsetButton               matlab.ui.control.StateButton
        XcoordEditFieldLabel          matlab.ui.control.Label
        XcoordEditField               matlab.ui.control.NumericEditField
        YcoordEditFieldLabel          matlab.ui.control.Label
        YcoordEditField               matlab.ui.control.NumericEditField
        XvoltageEditFieldLabel        matlab.ui.control.Label
        XvoltageEditField             matlab.ui.control.NumericEditField
        YvoltageEditFieldLabel        matlab.ui.control.Label
        YvoltageEditField             matlab.ui.control.NumericEditField
        ZcoordEditFieldLabel          matlab.ui.control.Label
        ZcoordEditField               matlab.ui.control.NumericEditField
        ZvoltageEditFieldLabel        matlab.ui.control.Label
        ZvoltageEditField             matlab.ui.control.NumericEditField
        MinColorbarEditFieldLabel     matlab.ui.control.Label
        MinColorbarEditField          matlab.ui.control.NumericEditField
        MaxColorbarEditFieldLabel     matlab.ui.control.Label
        MaxColorbarEditField          matlab.ui.control.NumericEditField
        PanelTraza                    matlab.ui.container.Panel
        StartTraceButton              matlab.ui.control.Button
        StopTraceButton               matlab.ui.control.StateButton
        IntTimemsEditFieldLabel       matlab.ui.control.Label
        IntTimemsEditField            matlab.ui.control.NumericEditField
        NBufferEditFieldLabel         matlab.ui.control.Label
        NBufferEditField              matlab.ui.control.NumericEditField
        NotifyScansSpinnerLabel       matlab.ui.control.Label
        NotifyScansSpinner            matlab.ui.control.Spinner
        MeanEditFieldLabel            matlab.ui.control.Label
        MeanEditField                 matlab.ui.control.NumericEditField
        STDEditFieldLabel             matlab.ui.control.Label
        STDEditField                  matlab.ui.control.NumericEditField
        SavingDataSwitchLabel         matlab.ui.control.Label
        SavingDataSwitch              matlab.ui.control.Switch
        Panel3                        matlab.ui.container.Panel
        SaveCurrentScanSwitchLabel    matlab.ui.control.Label
        SaveCurrentScanSwitch         matlab.ui.control.Switch
        Panel4                        matlab.ui.container.Panel
        ScanButton                    matlab.ui.control.Button
        ScanSizeumEditFieldLabel      matlab.ui.control.Label
        ScanSizeumEditField           matlab.ui.control.NumericEditField
        ScanFreqEditFieldLabel        matlab.ui.control.Label
        ScanFreqEditField             matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel         matlab.ui.control.Label
        XoffsetEditField              matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel         matlab.ui.control.Label
        YoffsetEditField              matlab.ui.control.NumericEditField
        StopscanButton                matlab.ui.control.Button
        ScanNpixelsEditFieldLabel     matlab.ui.control.Label
        ScanNpixelsEditField          matlab.ui.control.NumericEditField
        CountstimemsEditFieldLabel    matlab.ui.control.Label
        CountstimemsEditField         matlab.ui.control.NumericEditField
        SettlingtimemsEditFieldLabel  matlab.ui.control.Label
        SettlingtimemsEditField       matlab.ui.control.NumericEditField
        SaveScanButton                matlab.ui.control.Button
        SampleNameEditFieldLabel      matlab.ui.control.Label
        SampleNameEditField           matlab.ui.control.EditField
        FilterNameEditFieldLabel      matlab.ui.control.Label
        FilterNameEditField           matlab.ui.control.EditField
        DetectorsNameEditFieldLabel   matlab.ui.control.Label
        DetectorsNameEditField        matlab.ui.control.EditField
        PoweruWEditFieldLabel         matlab.ui.control.Label
        PoweruWEditField              matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        Panel5                        matlab.ui.container.Panel
        UpYButton                     matlab.ui.control.StateButton
        DownYButton                   matlab.ui.control.StateButton
        RightXButton                  matlab.ui.control.StateButton
        LeftXButton                   matlab.ui.control.StateButton
        ZSliderLabel                  matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        DecZButton                    matlab.ui.control.StateButton
        IncZButton                    matlab.ui.control.StateButton
        MovenmEditFieldLabel          matlab.ui.control.Label
        MovenmEditField               matlab.ui.control.NumericEditField
        MaxZEditFieldLabel            matlab.ui.control.Label
        MaxZEditField                 matlab.ui.control.NumericEditField
        MinZEditFieldLabel            matlab.ui.control.Label
        MinZEditField                 matlab.ui.control.NumericEditField
        ActPanelButton                matlab.ui.control.Button
        Goto000Button                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        DAQSession = [] % Handle to DAQ session
        
        DAQClkTraceSession = [] % Handle to DAQ session
        
        TraceAvailableListener % Listener for DAQ session 'DataAvailable' event
        
        TimestampsFIFOBuffer % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        
        DataFIFOBuffer  % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        
        traceFigure % Handle a figura externa que plotea la traza
        axesTrace % Handle de los ejes de figura que plotea traza
        lineTrace % Handle a la linea del gráfico
        
        LogRequested % Logical value, indicates whether user selected to log data to file from the UI (set by LogdatatofileSwitch)
        LogSaveScan % Valor booleano para guardar datos tomados del scan
        TriggerTime % Acquisition start time stored as datetime
        TempFilename % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA') % Default MAT file name at app start
        Filepath = pwd % Default folder for saving the MAT file at app start
        
        % Propiedades asociadas a mapa PL
        
        DAQPiezoSession = [] % Sesion para manejar entradas y salidas de voltaje en piezo
        DAQScanSession = [] % Sesion para realizar scan
        
        transCoords = false % Bandera para trackear coordenadas actuales a scan
        piezoSettlingTime = 2E-3 % Tiempo que le toma al piezo moverse de un punto a otro
        
        xCoord % X coordinates scanned
        yCoord % Y coordinates scanned
        countsMatrix = [] % Matrix associated with counts/s at the given coordinates
        matrixPlot
        isClickedDown = false
        buttonDownTime % Variable para medir el tiempo que se mantiene presionado el clic
        
        currentVoltX  % Voltaje asociado a posicion actual X
        currentVoltY  % Voltaje asociado a posicion actual Y
        currentVoltZ % Voltaje asociado a posicion actual Z
        maxVoltPiezo = 0.25 % Voltaje máximo a aplicar al piezo
        
        hXLine  % Handle para la línea vertical
        hYLine  % Handle para la línea horizontal
        
        voltX = 0
        voltY = 0
        voltZ = 0
    end


    methods (Access = private)
        
        function dataAvailable_Callback(app, ~, event)
        %dataAvailable_Callback Executes on DAQ session DataAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
        
            if app.LogRequested
                % If Log data to file switch is on
                data = [event.TimeStamps, event.Data]' ;
                fwrite(app.TempFile, data, 'double');
                if event.TimeStamps(1)==0
                    app.TriggerTime = event.TriggerTime;
                end
            end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(abs(app.NBufferEditField.Value));
            
            % Definir variables que determinan tiempo de integración
            timeMs = app.IntTimemsEditField.Value;
            dT = timeMs/(1000*2);
            
            data = mean(diff(event.Data(:,1)))/(2*dT);
            
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data);
            if numel(app.DataFIFOBuffer) == buffersize
                app.MeanEditField.Value = mean(app.DataFIFOBuffer(1:end));
                app.STDEditField.Value = std(app.DataFIFOBuffer(1:end));
            end
            
            % Update plot data
            set(app.lineTrace ,'YData', app.DataFIFOBuffer);
            drawnow limitrate;
            
        end
        
        % Callback que se ejecuta al cerrar la figura externa
        function closeTrazaCallback(app, src, event)
            % Cerrar la sesión de DAQ
            StopTraceButtonValueChanged(app,[])
            % Cerrar la figura
            delete(src);
        end
        
        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
        %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
        %  the data in the buffer to BUFFERSIZE.
        %  input DATA is the existing data buffer (column vector Nx1).
        %  BUFFERSIZE is the desired buffer size (maximum number of rows in DATA buffer) and can be changed.
        %  DATABLOCK is a new data block to be added to the buffer (column vector Kx1).
        %  output DATA is the updated data buffer (column vector Mx1).
        
        % If the data size is greater than the buffer size, keep only the
        % the latest "buffer size" worth of data
        % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
            
        end
        
        function setAppViewState(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  STATE can be 'deviceselection', 'configuration', or 'acquisition'
        
            switch state              
                case 'configuration'
                    
                    app.ScanButton.Enable = 'on';
                    app.StopscanButton.Enable = 'off';
                    
                    if ~(isempty(app.DAQSession)) && app.DAQSession.IsRunning
                        app.StartTraceButton.Enable = 'off';
                        app.StopTraceButton.Enable = 'on';
                        app.SavingDataSwitch.Enable = 'off';
                        app.NotifyScansSpinner.Enable = 'off';
                        app.IntTimemsEditField.Enable = 'off';
                        app.NBufferEditField.Enable = 'off';
                    else
                        app.StartTraceButton.Enable = 'on';
                        app.StopTraceButton.Enable = 'off';
                        app.SavingDataSwitch.Enable = 'on';
                        app.NotifyScansSpinner.Enable = 'on';
                        app.IntTimemsEditField.Enable = 'on';
                        app.NBufferEditField.Enable = 'on';
                    end
                    
                    
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ScanFreqEditField.Enable = 'on';
                    app.ScanSizeumEditField.Enable = 'on';
                    app.ScanNpixelsEditField.Enable = 'on';
                    app.SettlingtimemsEditField.Enable = 'on';
                    app.CountstimemsEditField.Enable = 'on';
                    
                    app.LoadFigureButton.Enable = 'on';
                    app.Goto000Button.Enable = 'on';
                    app.SetoffsetButton.Enable = 'on';
                    app.UpYButton.Enable = 'on';
                    app.DownYButton.Enable = 'on';
                    app.LeftXButton.Enable = 'on';
                    app.RightXButton.Enable = 'on';
                    app.MinZEditField.Enable = 'on';
                    app.MaxZEditField.Enable = 'on';
                    app.ZSlider.Enable = 'on';
                    app.DecZButton.Enable = 'on';
                    app.IncZButton.Enable = 'on';
                    app.MovenmEditField.Enable = 'on';
                    app.XcoordEditField.Enable = 'on';
                    app.YcoordEditField.Enable = 'on';
                    app.ZcoordEditField.Enable = 'on';
                    
                case 'acquisition'
                    app.SavingDataSwitch.Enable = 'off';
                    app.NotifyScansSpinner.Enable = 'off';
                    app.IntTimemsEditField.Enable = 'off';
                    app.NBufferEditField.Enable = 'off';
                    app.StartTraceButton.Enable = 'off';
                    app.StopTraceButton.Enable = 'on';
                    
                case 'scanning'
                    app.SavingDataSwitch.Enable = 'on';
                    app.NotifyScansSpinner.Enable = 'on';
                    app.IntTimemsEditField.Enable = 'on';
                    app.NBufferEditField.Enable = 'on';
                    app.StartTraceButton.Enable = 'on';
                    app.ScanButton.Enable = 'off';
                    app.StopTraceButton.Enable = 'off';
                    app.StopscanButton.Enable = 'on';
                    
                    app.XoffsetEditField.Enable = 'off';
                    app.YoffsetEditField.Enable = 'off';
                    app.ScanFreqEditField.Enable = 'off';
                    app.ScanSizeumEditField.Enable = 'off';
                    app.ScanNpixelsEditField.Enable = 'off';
                    app.SettlingtimemsEditField.Enable = 'off';
                    app.CountstimemsEditField.Enable = 'off';
                    
                    app.LoadFigureButton.Enable = 'off';
                    app.Goto000Button.Enable = 'off';
                    app.SetoffsetButton.Enable = 'off';
                    app.UpYButton.Enable = 'off';
                    app.DownYButton.Enable = 'off';
                    app.LeftXButton.Enable = 'off';
                    app.RightXButton.Enable = 'off';
                    app.MinZEditField.Enable = 'off';
                    app.MaxZEditField.Enable = 'off';
                    app.ZSlider.Enable = 'off';
                    app.DecZButton.Enable = 'off';
                    app.IncZButton.Enable = 'off';
                    app.MovenmEditField.Enable = 'off';
                    app.XcoordEditField.Enable = 'off';
                    app.YcoordEditField.Enable = 'off';
                    app.ZcoordEditField.Enable = 'off';
                    
                case 'scanerFinished'
                    app.SavingDataSwitch.Enable = 'on';
                    app.NotifyScansSpinner.Enable = 'on';
                    app.IntTimemsEditField.Enable = 'on';
                    app.NBufferEditField.Enable = 'on';
                    app.StartTraceButton.Enable = 'on';
                    app.ScanButton.Enable = 'on';
                    app.StopTraceButton.Enable = 'off';
                    app.StopscanButton.Enable = 'off';
                    
                    app.XoffsetEditField.Enable = 'on';
                    app.YoffsetEditField.Enable = 'on';
                    app.ScanFreqEditField.Enable = 'on';
                    app.ScanSizeumEditField.Enable = 'on';
                    app.ScanNpixelsEditField.Enable = 'on';
                    app.SettlingtimemsEditField.Enable = 'on';
                    app.CountstimemsEditField.Enable = 'on';
                    
                    app.LoadFigureButton.Enable = 'off';
                    app.Goto000Button.Enable = 'off';
                    app.SetoffsetButton.Enable = 'off';
                    app.UpYButton.Enable = 'off';
                    app.DownYButton.Enable = 'off';
                    app.LeftXButton.Enable = 'off';
                    app.RightXButton.Enable = 'off';
                    app.MinZEditField.Enable = 'off';
                    app.MaxZEditField.Enable = 'off';
                    app.ZSlider.Enable = 'off';
                    app.DecZButton.Enable = 'off';
                    app.IncZButton.Enable = 'off';
                    app.MovenmEditField.Enable = 'off';
                    app.XcoordEditField.Enable = 'off';
                    app.YcoordEditField.Enable = 'off';
                    app.ZcoordEditField.Enable = 'off';


            end
        end
        
        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), 1) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
        end
        
        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Executes after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is launched from LiveDataAcquisitionCloseRequest.
        %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
        %   DAQ session and close file if logging.
        
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession)
                        stop(app.DAQClkTraceSession)
                        stop(app.DAQPiezoSession)
                        stop(app.DAQScanSession)
                        delete(app.TraceAvailableListener)
                        delete(app.DAQSession)
                        delete(app.DAQClkTraceSession)
                        delete(app.DAQPiezoSession)
                        delete(app.DAQScanSession)

                        
                        if app.LogRequested
                            fclose(app.TempFile);
                        end
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
            
        end
        
        function updateLogdatatofileSwitchComponents(app)
            value = app.SavingDataSwitch.Value;
            switch value
                case 'Off'
                    app.LogRequested = false;
                case 'On'
                    app.LogRequested = true;
            end
        end


        % Función que lee y actualiza voltajes del piezo
        
        function updatePiezoVoltage(app, sesion)
            
            % Leer voltajes
            switch sesion
                case 'piezo'
                    voltajes = inputSingleScan(app.DAQPiezoSession);
                    % Asignar voltajes leidos a variables de clase
                    
                    app.currentVoltX = recortarVoltaje(app, voltajes(1), 0.0, 10.0);
                    app.currentVoltY = recortarVoltaje(app, voltajes(2), 0.0, 10.0);
                    app.currentVoltZ = recortarVoltaje(app, voltajes(3), 0.0, 10.0);
                    
                case 'scan'
                    voltajes = inputSingleScan(app.DAQScanSession);
                    % Asignar voltajes leidos a variables de clase
                    
                    app.currentVoltX = recortarVoltaje(app, voltajes(2), 0.0, 10.0);
                    app.currentVoltY = recortarVoltaje(app, voltajes(3), 0.0, 10.0);
                    app.currentVoltZ = recortarVoltaje(app, voltajes(4), 0.0, 10.0);
            end


            % Actualizar valores actuales de voltaje en UI
            
            app.XvoltageEditField.Value = app.currentVoltX;
            app.YvoltageEditField.Value = app.currentVoltY;
            app.ZvoltageEditField.Value = app.currentVoltZ;
            
            app.XcoordEditField.Value = app.currentVoltX * 8;
            app.YcoordEditField.Value = app.currentVoltY * 8;
            app.ZcoordEditField.Value = app.currentVoltZ * 8;
            
            app.ZSlider.Value = app.currentVoltZ * 8;
            
        end
        
        % FUNCIONES ASOCIADAS A MAPA PL
        
        % Callback para greaficar matriz y setear listeners de interacción
        % con imagen
        
        function dataLoadedCallback(app)
            % Usar imagesc para graficar amtriz y setear límites
            app.matrixPlot = imagesc(app.UIAxes,app.xCoord, app.yCoord, app.countsMatrix);
            xlim(app.UIAxes, [min(app.xCoord), max(app.xCoord)]);
            ylim(app.UIAxes, [min(app.yCoord), max(app.yCoord)]);
            colorbar(app.UIAxes)
            
            % Inicializar líneas de coordenadas
            posX = app.currentVoltX * 8;
            posY = app.currentVoltY * 8;
            hold(app.UIAxes, 'on');
            app.hXLine = xline(app.UIAxes, posX, 'k');
            app.hYLine = yline(app.UIAxes, posY, 'k');
            hold(app.UIAxes, 'off');
            
            % Obtener la barra de herramientas del axes
            tb = axtoolbar(app.UIAxes);
            
            % Eliminar herramientas específicas
            delete(tb.Children);
            
            
            % Crear lsiteners de interaccion con figura para establecer
            % coordenadas
            set(app.matrixPlot, 'ButtonDownFcn', @(src, event)clickDown(app, src, event));
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event)whileClickDown(app, src, event));
            set(app.UIFigure, 'WindowButtonUpFcn', @(src, event)clickUp(app, src, event));
        end
        
        function clickDown(app, src, event)


            % Obtener la posición del click down
            
            pos = get(app.UIAxes, 'CurrentPoint');
            x = round(pos(1,1),4);
            y = round(pos(1,2),4);
            app.isClickedDown = true;
            %app.buttonDownTime = tic;
            
            % Verificar que el click se haya hecho dentro de los límites de la figura
            
            if x >= min(app.xCoord) && x <= max(app.xCoord) && y >= min(app.yCoord) && y <= max(app.yCoord)
                
                % Mostrar en UI posición clickeada
                
                app.XcoordEditField.Value = x;
                app.YcoordEditField.Value = y;
                
                % Actualizar las líneas de coordenadas
                
                set(app.hXLine, 'Value', x);
                set(app.hYLine, 'Value', y);
                
                % Actualizar la posición clickeada como voltaje final
                
                app.voltX = x/8;
                app.voltY = y/8;
                
                % Mostrar en UI voltaje asociado a la posición del click
                
                app.XvoltageEditField.Value = app.voltX;
                app.YvoltageEditField.Value = app.voltY;
                
                % Desplazar piezo desde posición anterior hasta donde se
                % hace click
                
                desplazar(app, 'piezo');
                
            end
        end
        
        function whileClickDown(app, src, event)
            
            % Obtener posiciones mientras se mantiene presionado el click
            
            if(app.isClickedDown==true)
                
                % Posición dad por el mouse
                
                pos = get(app.UIAxes, 'CurrentPoint');
                x = round(pos(1,1),4);
                y = round(pos(1,2),4);
                
                % Verificar que el mouse esté dentro de los límites de la figura
            
                if x >= min(app.xCoord) && x <= max(app.xCoord) && y >= min(app.yCoord) && y <= max(app.yCoord)
                    
                    % Mostrar en UI posición del mouse
                    
                    app.XcoordEditField.Value = x;
                    app.YcoordEditField.Value = y;
                    
                    % Actualizar la posición del mouse como voltaje final
                    
                    app.voltX = x/8;
                    app.voltY = y/8;
                    
                    % Mostrar en UI voltaje asociado a la posición del
                    % mouse
                    
                    app.XvoltageEditField.Value = app.voltX;
                    app.YvoltageEditField.Value = app.voltY;
                    
                    % Desplazar piezo desde posición anterior hasta donde se
                    % mantiene el mouse
                    outputSingleScan(app.DAQPiezoSession, [app.voltX, app.voltY, app.currentVoltZ]);
                    
                    % Actualizar voltajes del piezo
                    
                    currentVoltage = inputSingleScan(app.DAQPiezoSession);
                    
                    app.currentVoltX = currentVoltage(1);
                    app.currentVoltY = currentVoltage(2);
                    
                end
                
            end
            
        end
        
        function clickUp(app, src, event)
            
            % Obtener el tiempo que estuvo presionado el click
            
            %buttonUpTime = toc(app.buttonDownTime);
            %disp(buttonUpTime)
            app.isClickedDown = false;
            
            % Obtener las posiciones cuando se deja de presionar el click
            
            pos = get(app.UIAxes, 'CurrentPoint');
            x = round(pos(1,1),4);
            y = round(pos(1,2),4);
            
            % Actualizar las líneas de coordenadas en la figura
            
            set(app.hXLine, 'Value', x);
            set(app.hYLine, 'Value', y);
            
            % Verificar que el click se haya levantado dentro de los límites de la figura
            % y el tiempo que estuvo presionado
            
            if x >= min(app.xCoord) && x <= max(app.xCoord) && y >= min(app.yCoord) && y <= max(app.yCoord)
                
                % Mostrar en UI posición del mouse al levantr click
                
                app.XcoordEditField.Value = x;
                app.YcoordEditField.Value = y;
                
                % Actualizar la posición del click up como voltaje final
                
                app.voltX = x/8;
                app.voltY = y/8;
                
                % Mostrar en UI voltaje asociado a la posición del click up
                    
                app.XvoltageEditField.Value = app.voltX;
                app.YvoltageEditField.Value = app.voltY;
                
                    
                currentVoltage = inputSingleScan(app.DAQPiezoSession);
                    
                app.currentVoltX = currentVoltage(1);
                app.currentVoltY = currentVoltage(2);
                
            end
            
        end
        
        % Funciones para desplazar el piezo considerando un umbral de voltaje
        
        function voltajeActual = recortarVoltaje(app, voltaje, minVal, maxVal)
            voltajeActual = max(min(voltaje, maxVal), minVal);
        end

        
        % Crea vectores con voltajes de salida con cantidad de elementos
        % que depende de punto inicial y final
        
        function outVoltage = voltVector(app, voltPtoInicial, voltPtoFinal)
            
            % calcular el voltaje total a desplazar
            
            totalVoltStep = abs(voltPtoFinal-voltPtoInicial);
            
            % Número de steps que tendrá el movimiento
                
            intervalos = ceil(totalVoltStep/app.maxVoltPiezo) + 1; 
                
            % Camino de voltajes a seguir en el piezo durante el
            % desplazamiento
                
            outVoltage = linspace(voltPtoInicial, voltPtoFinal, intervalos);
            
        end
        
        % Iguala el largo de los vectores de voltaje de salida
        
        function [outVectorX, outVectorY, outVectorZ] = equalizeVectorLength(app, vectorVoltX, vectorVoltY, vectorVoltZ)
            
            % Obtener tamaño de los vectores
            
            sizeVoltX = length(vectorVoltX);
            sizeVoltY = length(vectorVoltY);
            sizeVoltZ = length(vectorVoltZ);
            
            % Crear vector con los tamaños calculados
            
            largoVoltajes = [sizeVoltX, sizeVoltY, sizeVoltZ];
            
            % Encontrar el tamaño del vector mas largo de voltajes
            
            maxLength = max(largoVoltajes);
            
            % Calcular cantidad de veces a repetir ultimo elemento para
            % cada salida de voltaje
            
            numRepVoltX = maxLength - sizeVoltX;
            numRepVoltY = maxLength - sizeVoltY;
            numRepVoltZ = maxLength - sizeVoltZ;
            
            % Homogeneizar el largo de las salidas de voltaje
            
            if numRepVoltX > 0
                outVectorX = [vectorVoltX, repelem(vectorVoltX(end), numRepVoltX)];
            else
                outVectorX = vectorVoltX;
            end
            
            if numRepVoltY > 0
                outVectorY = [vectorVoltY, repelem(vectorVoltY(end), numRepVoltY)];
            else
                outVectorY = vectorVoltY;
            end
            
            if numRepVoltZ > 0
                outVectorZ = [vectorVoltZ, repelem(vectorVoltZ(end), numRepVoltZ)];
            else
                outVectorZ = vectorVoltZ;
            end
        end
        
        
        % Desplaza el piezo de un punto a otro
        
        function desplazar(app, sesion)
            
            switch sesion
                case 'piezo'
                    s = app.DAQPiezoSession;
                case 'scan'
                    s = app.DAQScanSession;
            end
            
            if ~(isempty(s))
                % Crear vector con voltajes iniciales actuales y voltajes
                % finales
                
                voltInicial = [app.currentVoltX, app.currentVoltY, app.currentVoltZ];
                
                voltFinal = [app.voltX, app.voltY, app.voltZ];
                
                % Calcular distancia en cada coordenada
                
                distPorCoord = abs(voltFinal - voltInicial);
                
                % Verificar si hay saltos de voltaje por sobre umbral
                
                idxUmbral = find(distPorCoord > app.maxVoltPiezo);
                
                if( isempty(idxUmbral) )
                    
                    % Si todos los saltos de voltaje estan por debajo del
                    % umbral se hace output con voltajes finales
                    
                    outputSingleScan(s, voltFinal);
                    
                else
                    
                    % Si existen saltos de voltaje por sobre el umbral
                    % crearemos cola de voltaje del largo asociado al máximo
                    % salto
                    
                    % Calcular el vector con los voltajes de cada coordenada
                    
                    vectorVoltX = voltVector(app, app.currentVoltX, app.voltX);
                    vectorVoltY = voltVector(app, app.currentVoltY, app.voltY);
                    vectorVoltZ = voltVector(app, app.currentVoltZ, app.voltZ);
                    
                    % Igualar el largo de los vectores de voltajes de salida
                    
                    [vectorVoltX, vectorVoltY, vectorVoltZ] = equalizeVectorLength(app, vectorVoltX, vectorVoltY, vectorVoltZ);
                    
                    % Darle salida a los voltajes
                    for i = 1 : length(vectorVoltX)
                        outputSingleScan(s, [vectorVoltX(i), vectorVoltY(i), vectorVoltZ(i)]);
                    end
                    
                    
                end
                
            else
                
                switch sesion
                    case 'piezo'
                        iniciarSesionPiezo(app)
                        desplazar(app, 'piezo')
                    case 'scan'
                        iniciarSesionScan(app)
                        desplazar(app, 'scan')
                end
                
            end
            
        end
        
        function scanProcessData(app, event, idxInicioCuentas,idxFinCuentas)
 
            data = diff(event.Data);
            
            nPuntos = app.ScanNpixelsEditField.Value;
            
            integrationTime = (app.CountstimemsEditField.Value)/1000;
            
            bufferCuentas = zeros(nPuntos * nPuntos,1);
            
            for i=1:length(idxInicioCuentas)
                bufferCuentas(i) = sum(  data(idxInicioCuentas(i):idxFinCuentas(i))  )/integrationTime; 
            end
            
            app.countsMatrix = reshape(bufferCuentas, nPuntos, nPuntos)';


            app.matrixPlot = imagesc(app.UIAxes,app.xCoord, app.yCoord, app.countsMatrix);
            colorbar(app.UIAxes)
            xlim(app.UIAxes, [min(app.xCoord), max(app.xCoord)]);
            ylim(app.UIAxes, [min(app.yCoord), max(app.yCoord)]);
            
            % Obtener la barra de herramientas del axes
            tb = axtoolbar(app.UIAxes);
            
            % Eliminar herramientas específicas
            delete(tb.Children);


            imagesc(app.countsMatrix)
            disp(app.countsMatrix)


        end
        
        % Funciones para crear sesiones
        
        function iniciarSesionReloj(app, tarea)
            % Si la sesión ya fue creada cambiamos la frecuencia y se
            % inicia. En caso contrario se crea desde 0.
            
            if ~(isempty(app.DAQClkTraceSession))
                
                % Detener sesion
                stop(app.DAQClkTraceSession)
                
                % Cambiar frecuencia según tarea a realizar
                switch tarea
                    case 'traza'
                        % Obtener tiempo de integración para la traza
                        timeMs = app.IntTimemsEditField.Value;
                        dT = timeMs/(1000*2);
                        app.DAQClkTraceSession.Channels.Frequency = 1/(2*dT);
                    case 'scan'
                        % Obtener frecuencia ingresada por usuario
                        app.DAQClkTraceSession.Channels.Frequency = app.ScanFreqEditField.Value;
                end
                
                % Ejecutar en 2do plano generación de pulsos
                startBackground(app.DAQClkTraceSession)
                
            else
                % Crear sesion para generar pulsos como reloj externo
                s_clk = daq.createSession('ni');
                ch_clk = addCounterOutputChannel(s_clk, 'Dev1', 'ctr1', 'PulseGeneration');


                % Setear delay del pulso, duty cycle y que en estado de reposo esté al
                % máximo
                ch_clk.IdleState = "High";
                ch_clk.InitialDelay = 1E-3;
                ch_clk.DutyCycle = 0.5;
                
                % Frecuencia según tarea a realizar
                switch tarea
                    case 'traza'
                        % Obtener tiempo de integración para la traza
                        timeMs = app.IntTimemsEditField.Value;
                        dT = timeMs/(1000*2);
                        ch_clk.Frequency = 1/(2*dT);
                    case 'scan'
                        % Obtener frecuencia ingresada por usuario
                        ch_clk.Frequency = app.ScanFreqEditField.Value;
                end
                
                
                % Generar pulsos continuamente
                s_clk.IsContinuous = true;
                
                % Guardar sesion como variable de la clase
                app.DAQClkTraceSession = s_clk;
                
                % Ejecutar en 2do plano generación de pulsos
                startBackground(app.DAQClkTraceSession)
            end
            
        end
        
        function iniciarSesionTraza(app)
            
            if ~(isempty(app.DAQScanSession))
                
                % Si hay una sesion asociada al scan estará usando los
                % canales de la traza. 
                
                stop(app.DAQScanSession)
                release(app.DAQScanSession)
                delete(app.DAQScanSession)
                
                app.DAQScanSession = [];
                
                iniciarSesionTraza(app)
                
            else
                %Crear sesion principal para adquisición de cuentas
                s_in = daq.createSession('ni');
                
                % Agregar un canal de entrada para contador
                addCounterInputChannel(s_in, 'Dev1', 'ctr0', 'EdgeCount');
                
                % Configurar reloj para medir traza
                iniciarSesionReloj(app, 'traza')
                
                % Agregar reloj a sesion de traza
                s_in.addClockConnection('External', 'Dev1/PFI13', 'ScanClock');
                
                % Configurar la tasa de muestreo igual a frec. del reloj
                s_in.Rate = app.DAQClkTraceSession.Channels.Frequency;
                
                % Recibir datos de manera continua
                s_in.IsContinuous = true;
                
                % Configurar cantidad de datos para llamar al listener
                s_in.NotifyWhenDataAvailableExceeds = app.NotifyScansSpinner.Value;
                
                % Guardar sesion como variable de la clase
                app.DAQSession = s_in;
                
                % Inicializar listener como arreglo vacío.
                app.TraceAvailableListener = [];
            end
            
        end
        
        function iniciarSesionPiezo(app)
            
            if ~(isempty(app.DAQScanSession))
                
                % Si hay una sesion asociada al scan estará usando los
                % canales del piezo. 
                
                stop(app.DAQScanSession)
                release(app.DAQScanSession)
                delete(app.DAQScanSession)
                
                app.DAQScanSession = [];
                
                iniciarSesionPiezo(app)
                
            else
                % Crear sesion para entrada y salida de voltajes en piezo
                s = daq.createSession('ni');
                
                % El orden de las coordenadas va como X, Y, Z.
                % Crear salidas analógicas
                addAnalogOutputChannel(s, 'Dev1', 'ao0', 'Voltage');
                addAnalogOutputChannel(s, 'Dev1', 'ao1', 'Voltage');
                addAnalogOutputChannel(s, 'Dev1', 'ao2', 'Voltage');
                
                % Agregar entradas analógicas
                addAnalogInputChannel(s, 'Dev1', 'ai0', 'Voltage');
                addAnalogInputChannel(s, 'Dev1', 'ai4', 'Voltage');
                addAnalogInputChannel(s, 'Dev1', 'ai16', 'Voltage');
                
                
                % Guardar session como variable de clase
                app.DAQPiezoSession = s;
            end
        end
        
        function iniciarSesionScan(app)
            
            if ~(isempty(app.DAQSession))
                % Detener sesion de traza para usar sus canales
                stop(app.DAQSession)
                release(app.DAQSession)
                delete(app.DAQSession)
                
                app.DAQSession = [];
            end
            
            if ~(isempty(app.DAQPiezoSession))
                % Detener sesion de piezo para usar sus canales
                stop(app.DAQPiezoSession)
                release(app.DAQPiezoSession)
                delete(app.DAQPiezoSession)
                
                app.DAQPiezoSession = [];
            end
            
            % Si la sesión del scan está activa solo necesitamos actualizar
            % frecuencia
            if ~(isempty(app.DAQScanSession))
                % Actualizar frecuencia del reloj
                iniciarSesionReloj(app, 'scan')
                
            else
                % Actualizar frecuencia del reloj
                iniciarSesionReloj(app,'scan')
                
                %Crear sesion principal para el scan
                s_scan = daq.createSession('ni');
                
                % Agregar un canal de entrada para contador
                addCounterInputChannel(s_scan, 'Dev1', 'ctr0', 'EdgeCount');
                
                % Configurar reloj
                s_scan.addClockConnection('External', 'Dev1/PFI13', 'ScanClock');
                
                % Configurar la tasa de muestreo igual a frec. del reloj
                s_scan.Rate = app.ScanFreqEditField.Value;
                
                % Recibir datos de manera continua
                s_scan.IsContinuous = true;
                
                % Configuración de salidas de voltaje
                
                % El orden de las coordenadas va como X, Y, Z.
                % Crear salidas analógicas
                addAnalogOutputChannel(s_scan, 'Dev1', 'ao0', 'Voltage');
                addAnalogOutputChannel(s_scan, 'Dev1', 'ao1', 'Voltage');
                addAnalogOutputChannel(s_scan, 'Dev1', 'ao2', 'Voltage');
                
                % Agregar entradas analógicas
                addAnalogInputChannel(s_scan, 'Dev1', 'ai0', 'Voltage');
                addAnalogInputChannel(s_scan, 'Dev1', 'ai4', 'Voltage');
                addAnalogInputChannel(s_scan, 'Dev1', 'ai16', 'Voltage');
                
                % Guardar sesion como variable de la clase
                app.DAQScanSession = s_scan;
            end
            
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %===============================================================
            % Se iniciaran sesiones principales al inciar la aplicación
            % para evitar conflictos al ejecutar distintas funcionalidades.
            %===============================================================
            
            % Crear sesión para generación de pulsos a usar como reloj
            iniciarSesionReloj(app,'traza')
            
            % Crear sesión para medir la traza
            iniciarSesionTraza(app)
            
            % Crear sesión para manejar piezo
            iniciarSesionPiezo(app)
            
            %======================================================================
            %======================================================================
            
            
            updatePiezoVoltage(app,'piezo')
            
            app.countsMatrix = zeros(80,80);
            app.xCoord = linspace(0,80,81)';
            app.yCoord = linspace(0,80,81)';
            
            dataLoadedCallback(app)
            
            % configurar diponibilidad de interacciones en la GUI
            setAppViewState(app, 'configuration');
            drawnow
        end

        % Button pushed function: StartTraceButton
        function StartTraceButtonPushed(app, event)
            
            if ~(isempty(app.DAQSession))
                % Verificar si los datos serán guardados
                
                if app.LogRequested
                    % If Log data to file switch is on
                    % Create and open temporary binary file to log data to disk
                    app.TempFilename = tempname;
                    app.TempFile = fopen(app.TempFilename, 'w');
                end
                
                %======================================================================
                %===================== CONF. GRÁFICO TRAZA ======================
                
                % Setear buffers como arreglos vacíos previo a usarlos
                app.DataFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                
                % Crear figura externa a la app para mostrar traza
                app.traceFigure = figure('Name','Traza', 'Position', [100, 100, 800, 600], ...
                         'CloseRequestFcn', @(src, event) closeTrazaCallback(app, src, event));
                
                % Obtener el handle a los ejes de la figura
                app.axesTrace = axes(app.traceFigure);
                %Hacer plot sin datos
                app.lineTrace = plot(app.axesTrace, NaN, 'LineWidth',3,"Color",'k');
                
                % Conf. título y etiquetas
                title(app.axesTrace, 'Traza');
                xlabel(app.axesTrace, 'u.a');
                ylabel(app.axesTrace, 'cuentas/s');
                
                xlim(app.axesTrace,[0, app.NBufferEditField.Value]);
                set(app.axesTrace, 'FontSize', 32);
                
                %======================================================================
                %======================================================================
                
                % Crear listener e iniciar en 2do plano sesion de adquisición
                
                app.TraceAvailableListener = addlistener(app.DAQSession, 'DataAvailable', ...
                    @(src,event) dataAvailable_Callback(app, src, event));
                
                startBackground(app.DAQSession);
                %======================================================================
                %======================================================================
                
                % Modificar apariencia de botones y switches en UI
                
                setAppViewState(app, 'acquisition');
            else
                iniciarSesionTraza(app)
                StartTraceButtonPushed(app,event)
            end            

        end

        % Value changed function: StopTraceButton
        function StopTraceButtonValueChanged(app, event)
            
            % Detener adquisición de datos
            stop(app.DAQSession)
            
            % Eliminar e inicializar listener a array vacío
            delete(app.TraceAvailableListener);
            app.TraceAvailableListener = [];
            
            % Setear handles de gráfico de traza a array vacios
            app.traceFigure = [];
            app.axesTrace = [];
            app.lineTrace = [];
            
            % Activar interacción con componentes de la UI
            if ~(isempty(app.DAQPiezoSession))
                setAppViewState(app, 'configuration');
                
            else
                setAppViewState(app, 'scanerFinished');
            end
            
            
            % Verificar si hay que guardar archivos
            
            if app.LogRequested
                % Log data to file switch is on
                % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
                
                % Close temporary binary file
                fclose(app.TempFile);
                
                
                % Gather metadata in preparation for saving to MAT file
                % Store relevant Daq device info
                metadata.IntegrationTime = app.IntTimemsEditField.Value;
                metadata.NotifyScans = app.NotifyScansSpinner.Value;
                metadata.ClkFreq = 1000/(app.IntTimemsEditField.Value);
                metadata.TriggerTime = datetime(app.TriggerTime, 'ConvertFrom', 'datenum', 'TimeZone', 'local');
                
                % Open "Save As" to request destination MAT file path and file name from user
                [filename, pathname] = uiputfile({'*.mat'}, 'Save as',...
                    fullfile(app.Filepath, datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA')));
                
                if ~(isequal(filename,0) || isequal(pathname,0))
                    % User specified a file name in a folder with write permission
                    app.Filename = datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA');
                    app.Filepath = pathname;
                    cancelSaveAs = false;
                else
                    %  User clicked Cancel in "Save As" dialog
                    cancelSaveAs = true;
                end
                
                if ~cancelSaveAs
                    % Convert data from binary file to MAT file
                    matFilepath = fullfile(app.Filepath, datestr(datetime('now'), 'yyyy-mm-dd_HHMMSS_TRA'));
                    
                    
                    numColumns = 2;
                    binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
                    
                else
                    % User clicked Cancel in "Save As" dialog
                    % Inform user that data has not been saved
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            %Interacción para cerrar aplicación y usar callback para cerrar
            
            isAcquiring = ~isempty(app.DAQSession) && app.DAQSession.IsRunning;
            if isAcquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.UIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
        end

        % Value changed function: SavingDataSwitch
        function SavingDataSwitchValueChanged(app, event)
            updateLogdatatofileSwitchComponents(app)
        end

        % Value changed function: IntTimemsEditField
        function IntTimemsEditFieldValueChanged(app, event)
            
            % Ajustar datos necearios para notificar en listener de
            % adquisicion dependiendo de tiempo de integración ingresado
            % por usuario
            
            dT = app.IntTimemsEditField.Value;
            notify = app.NotifyScansSpinner.Value;
            
            prod = dT * notify;
            
            if(prod < 100)
                app.NotifyScansSpinner.Value = round(100/dT) + 1;
            
            end
            
        end

        % Value changed function: NotifyScansSpinner
        function NotifyScansSpinnerValueChanged(app, event)
            
            % Ajustar tiempo de integración de adquisición de datos
            % dependiendo de datos necesarios para notificar en listener
            % ingresados por usuario
            
            dT = app.IntTimemsEditField.Value;
            notify = app.NotifyScansSpinner.Value;
            
            prod = dT * notify;
            
            if(prod < 100)
                app.IntTimemsEditField.Value = ceil((100/notify)*10)/10 ;
            
            end
        end

        % Button pushed function: LoadFigureButton
        function LoadFigureButtonPushed(app, event)
            
            
            if ~(isempty(app.DAQPiezoSession))
                % Definir la carpeta actual
                currentFolder = pwd;
                
                % Definir el filtro de archivos para archivos .mat
                fileFilter = {'*.mat', 'Archivos MAT (*.mat)'};
                
                % Abrir el diálogo de selección de archivos
                [file, path] = uigetfile(fileFilter, 'Seleccionar un archivo MAT', currentFolder);
                
                % Verificar si se seleccionó un archivo
                if isequal(file, 0)
                    disp('Se ha cancelado cargar una figura');
                else
                    fullPath = fullfile(path, file);
                    disp(['Has seleccinado: ', fullPath]);
                    % Puedes agregar código adicional aquí para cargar el archivo o realizar otras acciones
                    
                    % Cargar datos para simular un escaneo
                    data = load(fullPath);  % Corregir la variable a 'fullPath'
                    app.xCoord = 8 * data.x;
                    app.yCoord = 8 * data.y;
                    app.countsMatrix = data.c;
                    
                    %======================================================================
                    %======================================================================
                    
                    % Leer voltajes actuales en piezo para cada coordenada
                    updatePiezoVoltage(app, 'piezo');
                    
                    % Llamar función que hace gráfico y configura listeners
                    dataLoadedCallback(app);
                end
            else
                iniciarSesionPiezo(app);
                LoadFigureButtonPushed(app, event);
            end
            
            
        end

        % Value changed function: NBufferEditField
        function NBufferEditFieldValueChanged(app, event)
            xlim(app.axesTrace, [0, app.NBufferEditField.Value])
        end

        % Value changed function: RightXButton
        function RightXButtonValueChanged(app, event)
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Obtener coordenada X actual
                
                x = app.currentVoltX * 8;
                
                % Obtener desplazamiento en nm para coord. X
                
                rightX = x + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if rightX >= min(app.xCoord) && rightX <= max(app.xCoord)
                    
                    % Modificar posicion en UI
                    
                    app.XcoordEditField.Value = rightX;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltX = rightX/8;
                    
                    % Mover el piezo solo en coordenada X
                    
                    outputSingleScan(app.DAQPiezoSession, [app.voltX, app.currentVoltY, app.currentVoltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.XvoltageEditField.Value = app.voltX;
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hXLine, 'Value', rightX);
                end
                
            else
                iniciarSesionPiezo(app)
                RightXButtonValueChanged(app, event)
            end
        end

        % Value changed function: LeftXButton
        function LeftXButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Obtener coordenada X actual
                
                x = app.currentVoltX * 8;
                
                % Obtener desplazamiento en nm para coord. X
                
                LeftX = x - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if LeftX >= min(app.xCoord) && LeftX <= max(app.xCoord)
                    
                    % Modificar posicion en UI
                    
                    app.XcoordEditField.Value = LeftX;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltX = LeftX/8;
                    
                    % Mover el piezo solo en coordenada X
                    
                    outputSingleScan(app.DAQPiezoSession, [app.voltX, app.currentVoltY, app.currentVoltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.XvoltageEditField.Value = app.voltX;
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hXLine, 'Value', LeftX);
                end
            else
                iniciarSesionPiezo(app)
                LeftXButtonValueChanged(app,event)
            end
            
            
        end

        % Value changed function: UpYButton
        function UpYButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo')
                
                % Obtener coordenada Y actual
                
                y = app.currentVoltY * 8;
                
                % Obtener desplazamiento en nm para coord. Y
                
                upY = y - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if upY >= min(app.yCoord) && upY <= max(app.yCoord)
                    
                    % Modificar posicion en UI
                    
                    app.YcoordEditField.Value = upY;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltY = upY/8;
                    
                    % Mover el piezo solo en coordenada Y
                    
                    outputSingleScan(app.DAQPiezoSession, [app.currentVoltX, app.voltY, app.currentVoltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.YvoltageEditField.Value = app.voltY;
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hYLine, 'Value', upY);
                end
                
            else
                iniciarSesionPiezo(app)
                UpYButtonValueChanged(app, event)
            end


        end

        % Value changed function: DownYButton
        function DownYButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Obtener coordenada Y actual
                
                y = app.currentVoltY * 8;
                
                % Obtener desplazamiento en nm para coord. Y
                
                DownY = y + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if DownY >= min(app.yCoord) && DownY <= max(app.yCoord)
                    
                    % Modificar posicion en UI
                    
                    app.YcoordEditField.Value = DownY;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltY = DownY/8;
                    
                    % Mover el piezo solo en coordenada Y
                    
                    outputSingleScan(app.DAQPiezoSession, [app.currentVoltX, app.voltY, app.currentVoltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.YvoltageEditField.Value = app.voltY;
                    
                    % Actualizar las líneas de coordenadas
                    set(app.hYLine, 'Value', DownY);
                end
                
            else
                iniciarSesionPiezo(app)
                DownYButtonValueChanged(app, event)
            end
            
            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Update current voltage
                updatePiezoVoltage(app, 'piezo');
                
                % Get the current value from slider
                zChanging = event.Value;
                
                % Update coord. Z in UI
                app.ZcoordEditField.Value = zChanging;
                
                % Set the final voltage to approach it
                app.voltZ = zChanging/8;
                app.voltX = app.currentVoltX;
                app.voltY = app.currentVoltY;
                disp(app.voltZ)
                
                desplazar(app, 'piezo');
                % Update voltage values
                updatePiezoVoltage(app, 'piezo');


            else
                iniciarSesionPiezo(app)
                ZSliderValueChanging(app, event)
            end
            
        end

        % Value changed function: IncZButton
        function IncZButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Obtener coord. Z actual
                
                z = app.currentVoltZ * 8;
                
                % Obtener desplazamiento en nm para coord. Z
                
                incZ = z + app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if incZ >= 0 && incZ <= 80
                    
                    % Modificar posicion en UI
                    
                    app.ZcoordEditField.Value = incZ;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltZ = incZ/8;
                    
                    % Mover el piezo solo en coordenada Z
                    
                    outputSingleScan(app.DAQPiezoSession, [app.currentVoltX, app.currentVoltY, app.voltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.ZvoltageEditField.Value = app.voltZ;
                    app.ZSlider.Value = incZ;
                    
                end
                
            else
                iniciarSesionPiezo(app)
                IncZButtonValueChanged(app,event)
            end


        end

        % Value changed function: DecZButton
        function DecZButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Obtener coord. Z actual
                
                z = app.currentVoltZ * 8;
                
                % Obtener desplazamiento en nm para coord. Z
                
                decZ = z - app.MovenmEditField.Value/1000;
                
                % Verificar que desplazamiento está dentro de los margenes
                
                if decZ >= 0 && decZ <= 80
                    
                    % Modificar posicion en UI
                    
                    app.ZcoordEditField.Value = decZ;
                    
                    % Obtener voltaje asociado a dezplazamiento
                    
                    app.voltZ = decZ/8;
                    
                    % Mover el piezo solo en coordenada Z
                    
                    outputSingleScan(app.DAQPiezoSession, [app.currentVoltX, app.currentVoltY, app.voltZ]);
                    
                    % Actualizar voltaje en UI
                    
                    app.ZvoltageEditField.Value = app.voltZ;
                    app.ZSlider.Value = decZ;
                    
                end
                
            else
                iniciarSesionPiezo(app)
                DecZButtonValueChanged(app, event)
            end
            
        end

        % Value changed function: Goto000Button
        function Goto000ButtonValueChanged(app, event)
            
            if ~(isempty(app.DAQPiezoSession))
                % Leer voltajes actuales en piezo
                
                updatePiezoVoltage(app, 'piezo');
                
                % Setear como voltaje final el 0,0,0
                
                app.voltX = 0;
                app.voltY = 0;
                app.voltZ = 0;
                
                % Desplazar piezo al 0,0,0
                
                desplazar(app, 'piezo');
                
                % Actualizar valores en UI
                
                app.XcoordEditField.Value = 0;
                app.YcoordEditField.Value = 0;
                app.ZcoordEditField.Value = 0;
                
                app.XvoltageEditField.Value = 0;
                app.YvoltageEditField.Value = 0;
                app.ZvoltageEditField.Value = 0;
                
                app.ZSlider.Limits = [0 max(app.ZSlider.Limits)];
                app.ZSlider.Value = 0;
                set(app.hXLine, 'Value', 0.5*(min(app.xCoord)+max(app.xCoord)));
                set(app.hYLine, 'Value', 0.5*(min(app.yCoord)+max(app.yCoord)));
                
            else
                iniciarSesionPiezo(app)
                Goto000ButtonValueChanged(app, event)
            end
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            setAppViewState(app, 'scanning')


            disp(1)
            disp(class(app.DAQScanSession))
            % Iniciar sesion que realiza scan
            iniciarSesionScan(app)
            disp(2)
            disp(class(app.DAQScanSession))
            
            %============================================================================
            %======================== C O O R D S.  S C A N =============================
            
            % Obtener puntos en los cuales se centra el scan
            centroScanX = app.XoffsetEditField.Value;
            centroScanY = app.YoffsetEditField.Value;
            
            % Offset del scan
            scanOffset = app.ScanSizeumEditField.Value * 0.5;
            
            % Obtener puntos iniciales del scan
            coordInicialX = centroScanX - scanOffset;
            coordInicialY = centroScanY - scanOffset;
            
            % Obtener puntos en los cuales debería terminar el scan
            coordFinalX = centroScanX + scanOffset;
            coordFinalY = centroScanY + scanOffset;
            
            % Actualizar voltajes actuales en piezo
            updatePiezoVoltage(app, 'scan')
            
            %============================================================================
            %==================== P A R A M E T R O S  S C A N ==========================
            
            % Cantidad de elementos minimos en output voltaje
            dimOutput = app.ScanFreqEditField.Value * 0.5;
            
            % Tiempo que consideramos que el piezo se mueve
            settlingTime = (app.SettlingtimemsEditField.Value)/1000;
            
            % Tiempo durante el cual se considerarán cuentas válidas
            integrationTime = (app.CountstimemsEditField.Value)/1000;
            
            % Cantidad de elementos en voltaje constante
            constVoltIntervalo = int64(app.ScanFreqEditField.Value * (settlingTime + integrationTime));
            
            % Elementos en cada intervalo de settling time
            periodo = 1/(app.ScanFreqEditField.Value);
            intervaloSettlingTime = int64(settlingTime/periodo);
            
            disp(3)

            disp(class(app.DAQScanSession))
            
            
            if (coordInicialX >= 0 && coordInicialY >= 0) && (coordFinalX <= 80 && coordFinalY <= 80)
                
                % Transformar posiciones iniciales a voltaje
                voltInicialX = coordInicialX/8;
                voltInicialY = coordInicialY/8;
                
                % Transformar posiciones finales a voltaje
                voltFinalX = coordFinalX/8;
                voltFinalY = coordFinalY/8;
                
                % Setear voltajes a los que iremos en el pto inicial 
                app.voltX = voltInicialX;
                app.voltY = voltInicialY;
                disp("1 voltaje z actual " + app.currentVoltZ)
                disp("1 voltaje final z " + app.voltZ)
                % Mantener Z en la posicion actual
                app.voltZ = app.currentVoltZ;
                disp("2 voltaje z actual " + app.currentVoltZ)
                disp("2 voltaje final z " + app.voltZ)
                
                disp(4)
                disp(class(app.DAQScanSession))
                % Desplazarse a las coordenadas iniciales ingresadas
                desplazar(app, 'scan')
                disp(5)
                disp(class(app.DAQScanSession))
                
                % Cantidad de puntos a escanear
                nPuntos = app.ScanNpixelsEditField.Value;
                
                % Total de elementos en output
                totalElementosOutput = constVoltIntervalo * nPuntos * nPuntos;
                
                % Verificar que la salida de voltaje tenga los elementos
                % minimos
                if(totalElementosOutput < dimOutput)
                    % Modificar nPuntos dados settlingTime e integrationTime
                    nPuntos = ceil(sqrt(0.5 * (settlingTime + integrationTime)^-1));
                    totalElementosOutput = constVoltIntervalo * nPuntos * nPuntos;
                    
                    app.ScanNpixelsEditField.Value = nPuntos;
                end
                disp(6)
                % Una vez adquiridos datos totales se activará listener
                app.DAQScanSession.NotifyWhenDataAvailableExceeds = totalElementosOutput;
                
                % Arreglo de voltajes a seguir en eje X
                voltPathX = linspace(voltInicialX, voltFinalX, nPuntos)';
                
                % Arreglo de voltajes a seguir en eje Y
                voltPathY = linspace(voltInicialY, voltFinalY, nPuntos)';
                
                % Guardar caminos de voltaje en coordenadas
                app.xCoord = voltPathX * 8;
                app.yCoord = voltPathY * 8;
                
                % Voltaje de ida en X
                subidaVoltX = repelem(voltPathX, constVoltIntervalo);
                
                % Voltaje de vuelta en X
                bajadaVoltX = flip(subidaVoltX);
                
                % Matriz con voltajes de subida y bajada concatenados
                matrizOutVoltX = [subidaVoltX bajadaVoltX];
                
                % Repetir el arreglo de subida y bajada considerando la cantidad de puntos
                matrizOutVoltX = repmat(matrizOutVoltX, 1, ceil(nPuntos * 0.5));
                
                % Salida de voltajes en eje X
                outVoltX = reshape(matrizOutVoltX, [], 1);
                
                % Output de voltajes a entregar en el piezo en dimension Y
                outVoltY = repelem(voltPathY, 2 * ceil(nPuntos * 0.5) * constVoltIntervalo);
                
                % Mantener output de voltaje en Z en 0
                outVoltZ = repelem(app.currentVoltZ, length(outVoltX))';
                disp(7)
                disp(class(app.DAQScanSession))
                % Poner voltajes a la cola
                queueOutputData(app.DAQScanSession,[outVoltX, outVoltY, outVoltZ]);
                
                % Indices correspondientes a último elemento de settling time y penúltimo
                % de las cuentas a considerar
                idxInicioCuentas = [intervaloSettlingTime:constVoltIntervalo:totalElementosOutput];
                idxFinCuentas = [constVoltIntervalo-1:constVoltIntervalo:totalElementosOutput];
                
                % Crear el listener para generar matriz
                addlistener(app.DAQScanSession, 'DataAvailable', ...
                    @(src, event) scanProcessData(app, event, idxInicioCuentas,idxFinCuentas));
                
                startBackground(app.DAQScanSession);
                
            else
                errordlg('Scan out of range! Please adjust the coordinate values.', 'Error');
                
            end
            
            setAppViewState(app, 'scanerFinished')
  
            
        end

        % Value changed function: SetoffsetButton
        function SetoffsetButtonValueChanged(app, event)
            
            % Marcar bandera de offset presionado
            app.transCoords = true;
            
            % Setear la posición actual para hacer un scan a su alrededor
            app.XoffsetEditField.Value = app.XcoordEditField.Value;
            app.YoffsetEditField.Value = app.YcoordEditField.Value;
        end

        % Button pushed function: StopscanButton
        function StopscanButtonPushed(app, event)
            if ~(isempty(app.DAQScanSession))
                stop(app.DAQScanSession)
                setAppViewState(app, 'acquisition');
            end
        end

        % Button pushed function: ActPanelButton
        function ActPanelButtonPushed(app, event)
            if ~(isempty(app.DAQScanSession)) || isempty(app.DAQPiezoSession)
                iniciarSesionPiezo(app)
                
                updatePiezoVoltage(app,'piezo')
                
                setAppViewState(app, 'configuration')
                
                
                % Inicializar líneas de coordenadas
                hold(app.UIAxes, 'on');
                app.hXLine = xline(app.UIAxes, app.XcoordEditField.Value, 'k');
                app.hYLine = yline(app.UIAxes, app.YcoordEditField.Value, 'k');
                hold(app.UIAxes, 'off');
    
                % Crear lsiteners de interaccion con figura para establecer
                % coordenadas
                set(app.matrixPlot, 'ButtonDownFcn', @(src, event)clickDown(app, src, event));
                set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event)whileClickDown(app, src, event));
                set(app.UIFigure, 'WindowButtonUpFcn', @(src, event)clickUp(app, src, event));
            end
        end

        % Value changed function: MinZEditField
        function MinZEditFieldValueChanged(app, event)
            value = app.MinZEditField.Value;
            app.ZSlider.Limits = [value max(app.ZSlider.Limits)];
        end

        % Value changed function: MaxZEditField
        function MaxZEditFieldValueChanged(app, event)
            value = app.MaxZEditField.Value;
            app.ZSlider.Limits = [min(app.ZSlider.Limits) value];
            
        end

        % Value changed function: SaveCurrentScanSwitch
        function SaveCurrentScanSwitchValueChanged(app, event)
            
            value = app.SaveCurrentScanSwitch.Value;
            switch value
                case 'On'
                    % Definir los datos a guardar
                    dataStruct.c = app.countsMatrix;
                    dataStruct.x = app.xCoord;
                    dataStruct.y = app.yCoord;
                    
                    % Definir la metadata
                    metadataStruct.posX = app.XoffsetEditField.Value;
                    metadataStruct.posY = app.YoffsetEditField.Value;
                    metadataStruct.scanSize = app.ScanSizeumEditField.Value;
                    metadataStruct.nPixels = app.ScanNpixelsEditField.Value;
                    metadataStruct.frecuencia = app.ScanFreqEditField.Value;
                    metadataStruct.settlingTime = app.SettlingtimemsEditField.Value;
                    metadataStruct.countsTime = app.CountstimemsEditField.Value;
                    
                    % Guardar datos y metadata en una estructura
                    scan.data = dataStruct;
                    scan.metadata = metadataStruct;
                    
                    % Obtener la fecha y hora actual
                    timestamp = datetime('now', 'Format', 'yyyy-MM-dd_HHmmss');
                    timestampStr = datestr(timestamp, 'yyyy-mm-dd_HHMMSS');
                    
                    % Generar el nombre del archivo
                    usrName = app.SampleNameEditField.Value;
                    filename = [usrName, timestampStr, '_SCAN.mat'];
                    
                    % Obtener la carpeta actual
                    currentFolder = pwd;
                    
                    % Generar la ruta completa del archivo
                    fullPath = fullfile(currentFolder, filename);
                    
                    % Guardar los datos en un archivo .mat
                    save(fullPath, 'scan');
                    
                    % Mostrar un mensaje de confirmación
                    disp(['Datos guardados en: ', fullPath]);
                    
                    app.SaveCurrentScanSwitch.Value = 'Off';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LiveViewPanel = uipanel(app.GridLayout);
            app.LiveViewPanel.Title = 'Panel';
            app.UIAxes = uiaxes(app.LiveViewPanel);
            title(app.UIAxes, 'PL MAP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadFigureButton = uibutton(app.LiveViewPanel, 'push');
            app.LoadFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFigureButtonPushed, true);
            app.LoadFigureButton.Text = 'Load Figure';
            app.SetoffsetButton = uibutton(app.LiveViewPanel, 'state');
            app.SetoffsetButton.ValueChangedFcn = createCallbackFcn(app, @SetoffsetButtonValueChanged, true);
            app.SetoffsetButton.Text = 'Set offset';
            app.XcoordEditFieldLabel = uilabel(app.LiveViewPanel);
            app.XcoordEditFieldLabel.Text = 'X coord.';
            app.XcoordEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.XcoordEditField.ValueDisplayFormat = '%.3f';
            app.XcoordEditField.Editable = 'off';
            app.YcoordEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YcoordEditFieldLabel.Text = 'Y coord.';
            app.YcoordEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YcoordEditField.ValueDisplayFormat = '%.3f';
            app.YcoordEditField.Editable = 'off';
            app.XvoltageEditFieldLabel = uilabel(app.LiveViewPanel);
            app.XvoltageEditFieldLabel.Text = 'X voltage';
            app.XvoltageEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.XvoltageEditField.ValueDisplayFormat = '%.3f';
            app.XvoltageEditField.Editable = 'off';
            app.YvoltageEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YvoltageEditFieldLabel.Text = 'Y voltage';
            app.YvoltageEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YvoltageEditField.ValueDisplayFormat = '%.3f';
            app.YvoltageEditField.Editable = 'off';
            app.ZcoordEditFieldLabel = uilabel(app.LiveViewPanel);
            app.ZcoordEditFieldLabel.Text = 'Z coord.';
            app.ZcoordEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.ZcoordEditField.ValueDisplayFormat = '%.3f';
            app.ZcoordEditField.Editable = 'off';
            app.ZvoltageEditFieldLabel = uilabel(app.LiveViewPanel);
            app.ZvoltageEditFieldLabel.Text = 'Z voltage';
            app.ZvoltageEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.ZvoltageEditField.ValueDisplayFormat = '%.3f';
            app.ZvoltageEditField.Editable = 'off';
            app.MinColorbarEditFieldLabel = uilabel(app.LiveViewPanel);
            app.MinColorbarEditFieldLabel.Text = 'Min. Colorbar';
            app.MinColorbarEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.MinColorbarEditField.Limits = [0 80];
            app.MaxColorbarEditFieldLabel = uilabel(app.LiveViewPanel);
            app.MaxColorbarEditFieldLabel.Text = 'Max. Colorbar';
            app.MaxColorbarEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.MaxColorbarEditField.Limits = [0 80];
            app.PanelTraza = uipanel(app.GridLayout);
            app.PanelTraza.Title = 'Panel2';
            app.StartTraceButton = uibutton(app.PanelTraza, 'push');
            app.StartTraceButton.ButtonPushedFcn = createCallbackFcn(app, @StartTraceButtonPushed, true);
            app.StartTraceButton.Text = 'Start Trace';
            app.StopTraceButton = uibutton(app.PanelTraza, 'state');
            app.StopTraceButton.ValueChangedFcn = createCallbackFcn(app, @StopTraceButtonValueChanged, true);
            app.StopTraceButton.Text = 'Stop Trace';
            app.IntTimemsEditFieldLabel = uilabel(app.PanelTraza);
            app.IntTimemsEditFieldLabel.Text = 'Int. Time (ms)';
            app.IntTimemsEditField = uieditfield(app.PanelTraza, 'numeric');
            app.IntTimemsEditField.Limits = [0.0005 1000];
            app.IntTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @IntTimemsEditFieldValueChanged, true);
            app.IntTimemsEditField.Value = 2;
            app.NBufferEditFieldLabel = uilabel(app.PanelTraza);
            app.NBufferEditFieldLabel.Text = 'N Buffer';
            app.NBufferEditField = uieditfield(app.PanelTraza, 'numeric');
            app.NBufferEditField.Limits = [25 1000];
            app.NBufferEditField.ValueDisplayFormat = '%.0f';
            app.NBufferEditField.ValueChangedFcn = createCallbackFcn(app, @NBufferEditFieldValueChanged, true);
            app.NBufferEditField.Value = 50;
            app.NotifyScansSpinnerLabel = uilabel(app.PanelTraza);
            app.NotifyScansSpinnerLabel.Text = 'Notify Scans';
            app.NotifyScansSpinner = uispinner(app.PanelTraza);
            app.NotifyScansSpinner.Limits = [25 50000];
            app.NotifyScansSpinner.ValueDisplayFormat = '%g';
            app.NotifyScansSpinner.ValueChangedFcn = createCallbackFcn(app, @NotifyScansSpinnerValueChanged, true);
            app.NotifyScansSpinner.Value = 50;
            app.MeanEditFieldLabel = uilabel(app.PanelTraza);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.PanelTraza, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.2e';
            app.MeanEditField.Editable = 'off';
            app.STDEditFieldLabel = uilabel(app.PanelTraza);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.PanelTraza, 'numeric');
            app.STDEditField.ValueDisplayFormat = '%.2e';
            app.STDEditField.Editable = 'off';
            app.SavingDataSwitchLabel = uilabel(app.PanelTraza);
            app.SavingDataSwitchLabel.Text = 'Saving Data';
            app.SavingDataSwitch = uiswitch(app.PanelTraza, 'slider');
            app.SavingDataSwitch.ValueChangedFcn = createCallbackFcn(app, @SavingDataSwitchValueChanged, true);
            app.Panel3 = uipanel(app.GridLayout);
            app.Panel3.Title = 'Panel3';
            app.SaveCurrentScanSwitchLabel = uilabel(app.Panel3);
            app.SaveCurrentScanSwitchLabel.Text = 'Save Current Scan';
            app.SaveCurrentScanSwitch = uiswitch(app.Panel3, 'slider');
            app.SaveCurrentScanSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveCurrentScanSwitchValueChanged, true);
            app.Panel4 = uipanel(app.GridLayout);
            app.Panel4.Title = 'Panel4';
            app.ScanButton = uibutton(app.Panel4, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ScanSizeumEditFieldLabel = uilabel(app.Panel4);
            app.ScanSizeumEditFieldLabel.Text = 'Scan Size (um)';
            app.ScanSizeumEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanSizeumEditField.Limits = [2 100];
            app.ScanSizeumEditField.Value = 50;
            app.ScanFreqEditFieldLabel = uilabel(app.Panel4);
            app.ScanFreqEditFieldLabel.Text = 'Scan Freq.';
            app.ScanFreqEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanFreqEditField.Limits = [100 100000];
            app.ScanFreqEditField.ValueDisplayFormat = '%.2e';
            app.ScanFreqEditField.Value = 50000;
            app.XoffsetEditFieldLabel = uilabel(app.Panel4);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.Panel4, 'numeric');
            app.XoffsetEditField.Limits = [0 80];
            app.XoffsetEditField.ValueDisplayFormat = '%.3f';
            app.XoffsetEditField.Value = 40;
            app.YoffsetEditFieldLabel = uilabel(app.Panel4);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.Panel4, 'numeric');
            app.YoffsetEditField.Limits = [0 80];
            app.YoffsetEditField.ValueDisplayFormat = '%.3f';
            app.YoffsetEditField.Value = 40;
            app.StopscanButton = uibutton(app.Panel4, 'push');
            app.StopscanButton.ButtonPushedFcn = createCallbackFcn(app, @StopscanButtonPushed, true);
            app.StopscanButton.Text = 'Stop scan';
            app.ScanNpixelsEditFieldLabel = uilabel(app.Panel4);
            app.ScanNpixelsEditFieldLabel.Text = 'Scan N. pixels';
            app.ScanNpixelsEditField = uieditfield(app.Panel4, 'numeric');
            app.ScanNpixelsEditField.Limits = [1 1000];
            app.ScanNpixelsEditField.Value = 30;
            app.CountstimemsEditFieldLabel = uilabel(app.Panel4);
            app.CountstimemsEditFieldLabel.Text = 'Counts time (ms)';
            app.CountstimemsEditField = uieditfield(app.Panel4, 'numeric');
            app.CountstimemsEditField.Limits = [0.0005 1000];
            app.CountstimemsEditField.Value = 1;
            app.SettlingtimemsEditFieldLabel = uilabel(app.Panel4);
            app.SettlingtimemsEditFieldLabel.Text = 'Settling time (ms)';
            app.SettlingtimemsEditField = uieditfield(app.Panel4, 'numeric');
            app.SettlingtimemsEditField.Limits = [0.0005 1000];
            app.SettlingtimemsEditField.Value = 2;
            app.SaveScanButton = uibutton(app.Panel4, 'push');
            app.SaveScanButton.Text = 'Save Scan';
            app.SampleNameEditFieldLabel = uilabel(app.Panel4);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.Panel4, 'text');
            app.SampleNameEditField.Value = 'sampleName';
            app.FilterNameEditFieldLabel = uilabel(app.Panel4);
            app.FilterNameEditFieldLabel.Text = 'Filter Name';
            app.FilterNameEditField = uieditfield(app.Panel4, 'text');
            app.FilterNameEditField.Value = 'filterName';
            app.DetectorsNameEditFieldLabel = uilabel(app.Panel4);
            app.DetectorsNameEditFieldLabel.Text = 'Detectors Name';
            app.DetectorsNameEditField = uieditfield(app.Panel4, 'text');
            app.DetectorsNameEditField.Value = 'APD';
            app.PoweruWEditFieldLabel = uilabel(app.Panel4);
            app.PoweruWEditFieldLabel.Text = 'Power (uW)';
            app.PoweruWEditField = uieditfield(app.Panel4, 'numeric');
            app.PoweruWEditField.Value = 2;
            app.WavelengthnmEditFieldLabel = uilabel(app.Panel4);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.Panel4, 'numeric');
            app.WavelengthnmEditField.Value = 532;
            app.Panel5 = uipanel(app.GridLayout);
            app.Panel5.Title = 'Panel5';
            app.UpYButton = uibutton(app.Panel5, 'state');
            app.UpYButton.ValueChangedFcn = createCallbackFcn(app, @UpYButtonValueChanged, true);
            app.UpYButton.Text = 'Up Y';
            app.DownYButton = uibutton(app.Panel5, 'state');
            app.DownYButton.ValueChangedFcn = createCallbackFcn(app, @DownYButtonValueChanged, true);
            app.DownYButton.Text = 'Down Y';
            app.RightXButton = uibutton(app.Panel5, 'state');
            app.RightXButton.ValueChangedFcn = createCallbackFcn(app, @RightXButtonValueChanged, true);
            app.RightXButton.Text = 'Right X';
            app.LeftXButton = uibutton(app.Panel5, 'state');
            app.LeftXButton.ValueChangedFcn = createCallbackFcn(app, @LeftXButtonValueChanged, true);
            app.LeftXButton.Text = 'Left X';
            app.ZSliderLabel = uilabel(app.Panel5);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.Panel5);
            app.ZSlider.Limits = [0 80];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.DecZButton = uibutton(app.Panel5, 'state');
            app.DecZButton.ValueChangedFcn = createCallbackFcn(app, @DecZButtonValueChanged, true);
            app.DecZButton.Text = 'Dec. Z';
            app.IncZButton = uibutton(app.Panel5, 'state');
            app.IncZButton.ValueChangedFcn = createCallbackFcn(app, @IncZButtonValueChanged, true);
            app.IncZButton.Text = 'Inc. Z';
            app.MovenmEditFieldLabel = uilabel(app.Panel5);
            app.MovenmEditFieldLabel.Text = {'Move (nm)'; ''};
            app.MovenmEditField = uieditfield(app.Panel5, 'numeric');
            app.MovenmEditField.Limits = [0 2000];
            app.MovenmEditField.ValueDisplayFormat = '%11.3g';
            app.MovenmEditField.Value = 30;
            app.MaxZEditFieldLabel = uilabel(app.Panel5);
            app.MaxZEditFieldLabel.Text = 'Max. Z';
            app.MaxZEditField = uieditfield(app.Panel5, 'numeric');
            app.MaxZEditField.Limits = [0 80];
            app.MaxZEditField.ValueChangedFcn = createCallbackFcn(app, @MaxZEditFieldValueChanged, true);
            app.MaxZEditField.Value = 80;
            app.MinZEditFieldLabel = uilabel(app.Panel5);
            app.MinZEditFieldLabel.Text = 'Min. Z';
            app.MinZEditField = uieditfield(app.Panel5, 'numeric');
            app.MinZEditField.Limits = [0 80];
            app.MinZEditField.ValueChangedFcn = createCallbackFcn(app, @MinZEditFieldValueChanged, true);
            app.ActPanelButton = uibutton(app.Panel5, 'push');
            app.ActPanelButton.ButtonPushedFcn = createCallbackFcn(app, @ActPanelButtonPushed, true);
            app.ActPanelButton.Text = 'Act. Panel';
            app.Goto000Button = uibutton(app.Panel5, 'state');
            app.Goto000Button.ValueChangedFcn = createCallbackFcn(app, @Goto000ButtonValueChanged, true);
            app.Goto000Button.Text = 'Go to (0, 0, 0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQDataAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end